classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        GridLayout5_2                 matlab.ui.container.GridLayout
        GridLayout10                  matlab.ui.container.GridLayout
        RsultatDWT2Label              matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        GridLayout9                   matlab.ui.container.GridLayout
        RappelLabel                   matlab.ui.control.Label
        NbrdimagespertinentesLabel_2  matlab.ui.control.Label
        PrcisionLabel_2               matlab.ui.control.Label
        SWT2Label                     matlab.ui.control.Label
        GridLayout8                   matlab.ui.container.GridLayout
        RappelLabel_2                 matlab.ui.control.Label
        NbrdimagespertinentesLabel    matlab.ui.control.Label
        PrcisionLabel                 matlab.ui.control.Label
        DWT2Label                     matlab.ui.control.Label
        valuationdesperformancesduCBIRLabel  matlab.ui.control.Label
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout11                  matlab.ui.container.GridLayout
        RsultatSWT2Label              matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        RechercheButton               matlab.ui.control.Button
        GridLayout3                   matlab.ui.container.GridLayout
        FiltreDropDown                matlab.ui.control.DropDown
        FiltreDropDownLabel           matlab.ui.control.Label
        NbrniveauEditField            matlab.ui.control.NumericEditField
        NbrniveauEditFieldLabel       matlab.ui.control.Label
        NbrsclasseEditField           matlab.ui.control.NumericEditField
        NbrsclasseEditFieldLabel      matlab.ui.control.Label
        NbrImageEditField             matlab.ui.control.NumericEditField
        NbrImageLabel                 matlab.ui.control.Label
        ParametreLabel                matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        OuvririmagerfrenceButton      matlab.ui.control.Button
        ProjetCIBR2023Label           matlab.ui.control.Label
    end

    
    properties (Access = private)
        cheminFichier % Description
        resultatImg % Description 
        imgsDwt2 % Description
        imgsSwt2 % Description
        precisionDwt2 % Description
        precisionSwt2 % Description
    end
    
    methods (Access = private)
        
       function afficherResultatRecherche(app) 
    % Get the number of images
    numImages = numel(app.imgsDwt2);
    
    % Delete existing UIAxes in GridLayout10
    delete(findobj(app.GridLayout10.Children));

    % Calculate the number of rows and columns for the grid layout
    numRows = 2; % Set the number of rows to 4
    numCols = ceil(numImages / numRows); % Adjust columns based on the number of rows
    
   
    % Create a grid of UIAxes for displaying images
    for i = 1:numImages
        % Create a UIAxes for each image
        uiAxes = uiaxes(app.GridLayout10);
        axis(uiAxes, 'off');
        % Configure the position in the GridLayout
        uiAxes.Layout.Row = ceil(i / numCols);
        uiAxes.Layout.Column = mod(i - 1, numCols) + 1;
        
        % Load the image
        img = imread(app.imgsDwt2{i});
         
        % Display the image in the UIAxes
        cla(uiAxes);
        imshow(img, 'Parent', uiAxes);  
    end
     % Refresh the layout of GridLayout10
    drawnow;
        end


        function  afficherResultatSwt2(app)
             % Get the number of images
        numImages = numel(app.imgsSwt2);
        
        % Delete existing UIAxes in GridLayout10
        delete(findobj(app.GridLayout11.Children));
    
        % Calculate the number of rows and columns for the grid layout
        numRows = 2; % Set the number of rows to 4
        numCols = ceil(numImages / numRows); % Adjust columns based on the number of rows
        
       
        % Create a grid of UIAxes for displaying images
        for i = 1:numImages
            % Create a UIAxes for each image
            uiAxes = uiaxes(app.GridLayout11);
            axis(uiAxes, 'off');
            % Configure the position in the GridLayout
            uiAxes.Layout.Row = ceil(i / numCols);
            uiAxes.Layout.Column = mod(i - 1, numCols) + 1;
            
            % Load the image
            img = imread(app.imgsSwt2{i});
             
            % Display the image in the UIAxes
            cla(uiAxes);
            imshow(img, 'Parent', uiAxes);  
        end
         % Refresh the layout of GridLayout10
        drawnow;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OuvririmagerfrenceButton
        function OuvririmagerfrenceButtonPushed(app, event)
             % Boîte de dialogue pour choisir le fichier image
         [fileName, filePath] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif', 'Images (*.png, *.jpg, *.jpeg, *.tif)'; '*.*', 'Tous les fichiers'}, 'Sélectionnez une image');
          % Vérifier si l'utilisateur a annulé la sélection
            if isequal(fileName, 0) || isequal(filePath, 0)
                return;
            end
        
            % Chemin complet de l'image
            imagePath = fullfile(filePath, fileName);
 
            app.Image.ImageSource=imread(imagePath);

            app.cheminFichier = imagePath;
        end

        % Callback function
        function ImageClicked(app, event)
            
        end

        % Callback function
        function ImageClicked2(app, event)
            
        end

        % Button pushed function: RechercheButton
        function RechercheButtonPushed(app, event)
            queryImagePath = app.Image;
            nbrNiveau = app.NbrniveauEditField;
            nbrClass = app.NbrsclasseEditField;
            nbrImage = app.NbrImageEditField;
            filtre = app.FiltreDropDown;

            if(isempty(queryImagePath.ImageSource))
                % Display a modal indicating that app.image is empty
            msgbox('Veuillez charger l''image de référence pour la requête.', 'Image de reference', 'modal'); 

            elseif(isempty(nbrNiveau.Value) || isempty(nbrClass.Value) || isempty(nbrImage.Value) ||  isempty(filtre.Value))
                     msgbox('Veuillez remplir tous les paramètres.', 'Parametre', 'modal'); 
            else 
               [imgsDwt2_,imgsSwt2_,precisionDwt2_,precisionSwt2_,imagesPertinentesRecupereesDWT2,imagesPertinentesRecupereesSwt2,rappelDwt2,rappelSwt2] = CBIR_Simple(app.cheminFichier,nbrNiveau.Value,nbrClass.Value,filtre.Value,nbrImage.Value);
               app.imgsDwt2 = imgsDwt2_;
                app.imgsSwt2 = imgsSwt2_;

                %supprimer d'avant
               app.PrcisionLabel.Text = "Précision: ";
               app.PrcisionLabel_2.Text = "Précision: ";

               app.RappelLabel_2.Text = "Rapel: ";
               app.RappelLabel.Text = "Rapel: ";

               app.NbrdimagespertinentesLabel.Text = ["Nbr d'images pertinentes: ", sprintf('%.2f', imagesPertinentesRecupereesDWT2)];
               app.NbrdimagespertinentesLabel_2.Text = ["Nbr d'images pertinentes: ", sprintf('%.2f', imagesPertinentesRecupereesSwt2)];  

                %Performances
               app.PrcisionLabel.Text = [app.PrcisionLabel.Text, sprintf('%.2f%%', precisionDwt2_)];
               app.PrcisionLabel_2.Text = [app.PrcisionLabel_2.Text, sprintf('%.2f%%', precisionSwt2_)];

               app.RappelLabel_2.Text = [app.RappelLabel_2.Text, sprintf('%.2f%', rappelDwt2)];
               app.RappelLabel.Text = [app.RappelLabel.Text, sprintf('%.2f%', rappelSwt2)];

                app.afficherResultatRecherche;
                app.afficherResultatSwt2;
               
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ProjetCIBR2023Label = uilabel(app.GridLayout2);
            app.ProjetCIBR2023Label.Text = 'Projet CIBR 2023';
            app.OuvririmagerfrenceButton = uibutton(app.GridLayout2, 'push');
            app.OuvririmagerfrenceButton.ButtonPushedFcn = createCallbackFcn(app, @OuvririmagerfrenceButtonPushed, true);
            app.OuvririmagerfrenceButton.Tag = 'btnChargerImage';
            app.OuvririmagerfrenceButton.Icon = fullfile(pathToMLAPP, '2849798_upload_multimedia_icon.png');
            app.OuvririmagerfrenceButton.Text = 'Ouvrir image référence';
            app.Image = uiimage(app.GridLayout2);
            app.Image.Tag = 'affichImag';
            app.ParametreLabel = uilabel(app.GridLayout2);
            app.ParametreLabel.Text = 'Parametre :';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.NbrImageLabel = uilabel(app.GridLayout3);
            app.NbrImageLabel.Text = 'Nbr Image';
            app.NbrImageEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NbrImageEditField.Limits = [2 Inf];
            app.NbrImageEditField.Value = 16;
            app.NbrsclasseEditFieldLabel = uilabel(app.GridLayout3);
            app.NbrsclasseEditFieldLabel.Text = 'Nbr s-classe';
            app.NbrsclasseEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NbrsclasseEditField.Limits = [1 24];
            app.NbrsclasseEditField.Value = 16;
            app.NbrniveauEditFieldLabel = uilabel(app.GridLayout3);
            app.NbrniveauEditFieldLabel.Text = 'Nbr niveau';
            app.NbrniveauEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NbrniveauEditField.Limits = [1 5];
            app.NbrniveauEditField.Value = 3;
            app.FiltreDropDownLabel = uilabel(app.GridLayout3);
            app.FiltreDropDownLabel.Text = 'Filtre';
            app.FiltreDropDown = uidropdown(app.GridLayout3);
            app.FiltreDropDown.Items = {'db1', 'db2', 'db3', 'sym4'};
            app.FiltreDropDown.Value = 'db1';
            app.RechercheButton = uibutton(app.GridLayout2, 'push');
            app.RechercheButton.ButtonPushedFcn = createCallbackFcn(app, @RechercheButtonPushed, true);
            app.RechercheButton.Text = 'Recherche';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.RsultatSWT2Label = uilabel(app.GridLayout5);
            app.RsultatSWT2Label.Text = 'Résultat SWT2:';
            app.GridLayout11 = uigridlayout(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.valuationdesperformancesduCBIRLabel = uilabel(app.GridLayout6);
            app.valuationdesperformancesduCBIRLabel.Text = 'Évaluation des performances du CBIR:';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.DWT2Label = uilabel(app.GridLayout8);
            app.DWT2Label.Text = 'DWT2:';
            app.PrcisionLabel = uilabel(app.GridLayout8);
            app.PrcisionLabel.Text = 'Précision : ';
            app.NbrdimagespertinentesLabel = uilabel(app.GridLayout8);
            app.NbrdimagespertinentesLabel.Text = 'Nbr d''images pertinentes:';
            app.RappelLabel_2 = uilabel(app.GridLayout8);
            app.RappelLabel_2.Text = 'Rappel:';
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.SWT2Label = uilabel(app.GridLayout9);
            app.SWT2Label.Text = 'SWT2:';
            app.PrcisionLabel_2 = uilabel(app.GridLayout9);
            app.PrcisionLabel_2.Text = 'Précision :';
            app.NbrdimagespertinentesLabel_2 = uilabel(app.GridLayout9);
            app.NbrdimagespertinentesLabel_2.Text = 'Nbr d''images pertinentes: ';
            app.RappelLabel = uilabel(app.GridLayout9);
            app.RappelLabel.Text = 'Rappel :';
            app.GridLayout5_2 = uigridlayout(app.GridLayout4);
            app.RsultatDWT2Label = uilabel(app.GridLayout5_2);
            app.RsultatDWT2Label.Text = 'Résultat DWT2:';
            app.GridLayout10 = uigridlayout(app.GridLayout5_2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end